classdef FEMAlva < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FEMBeamVisualizerUIFigure  matlab.ui.Figure
        AboutMenu                  matlab.ui.container.Menu
        DatabasePanel              matlab.ui.container.Panel
        Button                     matlab.ui.control.Button
        LocalArquivo               matlab.ui.control.EditField
        CalcularButton             matlab.ui.control.Button
        DirectionButtonGroup       matlab.ui.container.ButtonGroup
        d1Button                   matlab.ui.control.RadioButton
        d2Button                   matlab.ui.control.RadioButton
        d3Button                   matlab.ui.control.RadioButton
        ResulttypeButtonGroup      matlab.ui.container.ButtonGroup
        DisplacementButton         matlab.ui.control.RadioButton
        TensionButton              matlab.ui.control.RadioButton
        GaussIntpointsButtonGroup  matlab.ui.container.ButtonGroup
        x3Button                   matlab.ui.control.RadioButton
        x2Button                   matlab.ui.control.RadioButton
        x1Button                   matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        gauss = '1x1';
        resultados = 'Tension';
        direcao = 'xx';
        fn = '';
        v = 0;

        DISP = [];
        NEsup = [];
        CoordNos = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes.XAxis.Visible = 'off';
            app.UIAxes.YAxis.Visible = 'off';
            app.UIAxes.ZAxis.Visible = 'off';
            app.UIAxes.Interactions = [];
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path] = uigetfile('*.xlsx');
            delete(f);
            if file
                app.fn = strcat(path,file);

                app.LocalArquivo.Value = file;
            end

            app.v = 0;
            app.DISP = [];
            app.NEsup = [];
            app.CoordNos = [];

        end

        % Selection changed function: GaussIntpointsButtonGroup
        function GaussIntpointsButtonGroupSelectionChanged(app, event)
            app.gauss = app.GaussIntpointsButtonGroup.SelectedObject.Text;

            app.v = 0;
            app.DISP = [];
            app.NEsup = [];
            app.CoordNos = [];

        end

        % Selection changed function: ResulttypeButtonGroup
        function ResulttypeButtonGroupSelectionChanged(app, event)
            app.resultados = app.ResulttypeButtonGroup.SelectedObject.Text;

            if isequal(app.resultados, 'Displacement')
                app.direcao = 'x';
                app.d3Button.Text = 'x';
                app.d2Button.Text = 'y';
                app.d1Button.delete;
            else
                app.direcao = 'xx';
                app.d3Button.Text = 'xx';
                app.d2Button.Text = 'yy';
                app.d1Button = uiradiobutton(app.DirectionButtonGroup);
                app.d1Button.Text = 'xy';
            end

            
        end

        % Selection changed function: DirectionButtonGroup
        function DirectionButtonGroupSelectionChanged(app, event)
            app.direcao = app.DirectionButtonGroup.SelectedObject.Text;
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if app.v == 0
                %Coleta de dados a partir do excel
                Coord = xlsread(app.fn, "Coord");       %Coordenadas dos elementos isoparametricos
                Connec = xlsread(app.fn, "Connec");     %Numero dos nós de cada elemento
                GL = xlsread(app.fn, "GLn");            %Numeração de graus de  liberdade por nó
                CC = xlsread(app.fn, "CC");             %Condições de contorno
                P = xlsread(app.fn, "Carregamento");    %Carregamento
                dg = xlsread(app.fn, "Geral");          %Dados gerais do elemento estruturas
    
                %Tratamento dos dados obtidos do excel
                E = dg(1);                                          %Modulo de elasticidade em MPa
                ni = dg(2);                                         %Ni
                e = dg(3);                                          %Espessura em mm
                l = dg(4);                                          %Comprimento da viga em cm
                h = dg(5);                                          %Altura da viga em cm
                nElem = height(Connec);                             %Quantidade de elementos
                app.CoordNos = Coord;app.CoordNos(:, 1) = [];               %Matriz com coordenadas de todos os nós
                NE = Connec;NE(:, 1) = [];                          %Vetor com nós por elemento
                GL(:, 1) = [];                                      %Tratamento da matriz de graus de liberdade
                for i = 1: height(NE)                               %Cria matriz GLE - Graus de Liberdade por Elemento
                    for j = 1: length(NE(i, :))
                        GLE(i, ((2 * j) - 1)) = GL(NE(i, j), 1);
                        GLE(i, (2 * j)) = GL(NE(i, j), 2);
                    end
                end
                Fini = zeros((2 * height(GL)), 1);                  %Criação do vetor de forças iniciais
                for i = 1: height(P)
                    for j = 1: 2
                        Fini(GL(P(i, 1), j)) = P(i, (1 + j));
                    end
                end
                u = ones((2 * height(GL)), 1);                      %Criação do vetor de deslocamentos
                for i = 1: height(CC)
                    for j = 1: 2
                        u(GL(CC(i, 1), j)) = CC(i, (1 + j));
                    end
                end
                
                elem(nElem) = ElemQ8();
    
                if isequal(app.gauss, '1x1')
                    nGauss = 1;
                elseif isequal(app.gauss, '2x2')
                    nGauss = 2;
                elseif isequal(app.gauss, '3x3')
                    nGauss = 3;
                end
                
                for i = 1: nElem
                    elem(i) = ElemQ8(E, ni, e, nGauss, NE(i,:), app.CoordNos);
                end
                
                B = Barra(E, ni, e, l, h,8, elem, GLE, GL, NE, CC, Fini);
                u = B.d;
                ffin = B.P;
    
                %Plot
                %Codigo desnecessariamente complicado pra organizar a matriz de conexões
                app.NEsup = NE;
                for i = 1: 8
                    if i == 1
                        n = 1;
                    elseif rem(i, 2)
                        n = n - 4;
                    else
                        n = n + 3;
                    end
                    app.NEsup(:, i) = NE(:, n);
                    n = n + 1;
                end
                
                %Organiza os valores dos resultados em vetores de coordenadas
                for i = 1: +2: height(u)
                    app.DISP(i, :) = [u(i, 1) u((i + 1), 1)];
                end
                for i = (height(u) - 2): -2: 1
                    app.DISP(i, :) = [];
                end
                for i = 1: 3
                    app.DISP(:, (2 + i)) = B.sig(:, i);
                end
    
                app.v = 1;
            end

            %Plot dos resultados
            newplot(app.UIAxes);
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes.XAxis.Visible = 'off';
            app.UIAxes.YAxis.Visible = 'off';
            app.UIAxes.ZAxis.Visible = 'off';
            app.UIAxes.Interactions = [];
            
            if isequal(app.resultados, 'Displacement')
                if isequal(app.direcao, 'x')
                    dd = 1;
                elseif isequal(app.direcao, 'y')
                    dd = 2;
                end
            elseif isequal(app.resultados, 'Tension')
                if isequal(app.direcao, 'xx')
                    dd = 3;
                elseif isequal(app.direcao, 'yy')
                    dd = 4;
                elseif isequal(app.direcao, 'xy')
                    dd = 5;
                end
            end
            U2 = app.DISP(:, dd);
%             cmin = min(U2);
%             cmax = max(U2);
%             caxis([cmin cmax]);
            patch(app.UIAxes, 'Faces', app.NEsup, 'Vertices', app.CoordNos, 'FaceVertexCData', U2, 'Facecolor', 'interp', 'Marker', '.');
            colormap(app.UIAxes, 'jet');
            colorbar(app.UIAxes);

        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            uialert(app.FEMBeamVisualizerUIFigure, {'<center>Program for visualization of F.E.M. on a beam element using Q8 elements</center>';'';'Developed by: <ul><li>João Guilherme M. Alvarez</li></ul>';'';'<p style="text-align: justify;">Users are free to use the program for educational or professional activities. However, neither the authors, nor no stitution are responsible for any use or misuse of the program and the results. The aforementioned assume no liability or responsibility to any person or company for direct or indirect damages resulting from the use of any information or the use of any of the software made available here. The user is responsible for any and all conclusions made while using the program.</p>'},'PSBeam 1.0', 'Icon', 'none', 'Interpreter', 'html');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FEMBeamVisualizerUIFigure = uifigure('Visible', 'off');
            app.FEMBeamVisualizerUIFigure.Name = 'F.E.M. Beam Visualizer';
            app.FEMBeamVisualizerUIFigure.Icon = 'icon.jpg';
            app.AboutMenu = uimenu(app.FEMBeamVisualizerUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.FEMBeamVisualizerUIFigure);
            app.GaussIntpointsButtonGroup = uibuttongroup(app.FEMBeamVisualizerUIFigure);
            app.GaussIntpointsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GaussIntpointsButtonGroupSelectionChanged, true);
            app.GaussIntpointsButtonGroup.Title = 'Gauss Int. points';
            app.x1Button = uiradiobutton(app.GaussIntpointsButtonGroup);
            app.x1Button.Text = '1x1';
            app.x1Button.Value = true;
            app.x2Button = uiradiobutton(app.GaussIntpointsButtonGroup);
            app.x2Button.Text = '2x2';
            app.x3Button = uiradiobutton(app.GaussIntpointsButtonGroup);
            app.x3Button.Text = '3x3';
            app.ResulttypeButtonGroup = uibuttongroup(app.FEMBeamVisualizerUIFigure);
            app.ResulttypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResulttypeButtonGroupSelectionChanged, true);
            app.ResulttypeButtonGroup.Title = 'Result type';
            app.TensionButton = uiradiobutton(app.ResulttypeButtonGroup);
            app.TensionButton.Text = 'Tension';
            app.TensionButton.Value = true;
            app.DisplacementButton = uiradiobutton(app.ResulttypeButtonGroup);
            app.DisplacementButton.Text = 'Displacement';
            app.DirectionButtonGroup = uibuttongroup(app.FEMBeamVisualizerUIFigure);
            app.DirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DirectionButtonGroupSelectionChanged, true);
            app.DirectionButtonGroup.Title = 'Direction';
            app.d3Button = uiradiobutton(app.DirectionButtonGroup);
            app.d3Button.Text = 'xx';
            app.d3Button.Value = true;
            app.d2Button = uiradiobutton(app.DirectionButtonGroup);
            app.d2Button.Text = 'yy';
            app.d1Button = uiradiobutton(app.DirectionButtonGroup);
            app.d1Button.Text = 'xy';
            app.CalcularButton = uibutton(app.FEMBeamVisualizerUIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.DatabasePanel = uipanel(app.FEMBeamVisualizerUIFigure);
            app.DatabasePanel.Title = 'Database';
            app.LocalArquivo = uieditfield(app.DatabasePanel, 'text');
            app.LocalArquivo.Editable = 'off';
            app.Button = uibutton(app.DatabasePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'folder.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.FEMBeamVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FEMAlva

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FEMBeamVisualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FEMBeamVisualizerUIFigure)
        end
    end
end